classdef exer_3_12 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Base_freq_amp_ths          matlab.ui.control.Spinner
        Label_4                    matlab.ui.control.Label
        Harmonic_search_interval   matlab.ui.control.Spinner
        Label_7                    matlab.ui.control.Label
        Harmonic_search_amp_ths    matlab.ui.control.Spinner
        Label_6                    matlab.ui.control.Label
        Base_freq_search_interval  matlab.ui.control.Spinner
        Label_5                    matlab.ui.control.Label
        HzListBox                  matlab.ui.control.ListBox
        HzListBoxLabel             matlab.ui.control.Label
        Spinner_2                  matlab.ui.control.Spinner
        Label_3                    matlab.ui.control.Label
        Spinner                    matlab.ui.control.Spinner
        Label                      matlab.ui.control.Label
        Button_2                   matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        EditField                  matlab.ui.control.EditField
        RightPanel                 matlab.ui.container.Panel
        UIFigBeat                  matlab.ui.control.UIAxes
        UIFigSpec                  matlab.ui.control.UIAxes
        UIFigWave                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        AudioData           double
        Fs                  double
        OnAnalyzing        
        Tone_specs
        Base_freq_list
        Ratio               double
        Interval            double
        % Base_freq_amp_ths   double
        % Base_freq_search_interval   double
        % Harmonic_search_amp_ths     double
        % Harmonic_search_interval    double
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField.Value = '音乐合成所需资源\fmt.wav';
            app.OnAnalyzing = false;
            app.Ratio = app.Spinner.Value;
            app.Interval = app.Spinner_2.Value;
        end

        % Button pushed function: Button_3
        function selectFileButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.wav', 'Select Audio File');
            if isequal(filename, 0)
                return;
            end
            full_path = fullfile(filepath, filename);
            app.EditField.Value = full_path;
        end

        % Button pushed function: Button
        function OnAnalyzePushd(app, event)
            if (~app.OnAnalyzing)
                try
                    app.OnAnalyzing = true;
                    % read data
                    [app.AudioData, app.Fs] = audioread(app.EditField.Value);
                    % analyze data
                    [fmt, env, peaks, valleys, app.Tone_specs, app.Base_freq_list] = ...
                        analyze_wave( ...
                            app.AudioData, ...
                            app.Fs, ...
                            app.Ratio, ...
                            app.Interval, ...
                            app.Base_freq_amp_ths.Value, ...
                            app.Base_freq_search_interval.Value, ...
                            app.Harmonic_search_amp_ths.Value, ...
                            app.Harmonic_search_interval.Value ...
                        ) ...
                    ;
                    % plot result
                    plot_wave = @(ui, wave) plot(ui, plot_wave_t(app.AudioData, app.Fs), wave);
                    plot_wave(app.UIFigWave, fmt);
                    app.UIFigBeat.NextPlot = "replacechildren";
                    plot_wave(app.UIFigBeat, env);
                    t = plot_wave_t(env, app.Fs);
                    app.UIFigBeat.NextPlot = 'add';
                    plot(app.UIFigBeat, t(peaks), env(peaks), 'o', 'MarkerSize', 5, 'MarkerFaceColor', 'none', 'Color', 'red');
                    plot(app.UIFigBeat, t(valleys), env(valleys), 'x', 'MarkerSize', 5, 'MarkerFaceColor', 'none', 'Color', 'blue');
                    plot(app.UIFigSpec, app.Tone_specs{1}{1}, app.Tone_specs{1}{2});
                    app.UIFigSpec.Title.String = ['Freq = ', num2str(app.Base_freq_list{1}), 'Hz'];
                    % list base freq
                    items = {};
                    for i = 1:length(app.Base_freq_list)
                        items{i} = [num2str(i), ': Freq=', num2str(app.Base_freq_list{i}), 'Hz'];
                    end
                    app.HzListBox.Items = items;
                    app.OnAnalyzing = false;
                catch exception
                    rethrow(exception);
                end
            end
        end

        % Clicked callback: HzListBox
        function OnListClicked(app, event)
            item = event.InteractionInformation.Item;
            plot(app.UIFigSpec, app.Tone_specs{item}{1}, app.Tone_specs{item}{2});
            app.UIFigSpec.Title.String = ['Freq = ', num2str(app.Base_freq_list{item}), 'Hz'];
        end

        % Value changed function: Spinner
        function OnRatioChanged(app, event)
            app.Ratio = app.Spinner.Value;
        end

        % Value changed function: Spinner_2
        function OnIntervalChanged(app, event)
            app.Interval = app.Spinner_2.Value;
        end

        % Button pushed function: Button_2
        function OnPlayPushd(app, event)
            play_dfh();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {251, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = '音乐合成播放器';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField.Editable = 'off';
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @selectFileButtonPushed, true);
            app.Button_3.Text = '选择音频样本';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @OnAnalyzePushd, true);
            app.Button.Text = '开始分析样本';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @OnPlayPushd, true);
            app.Button_2.Text = '播放东方红';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '最低峰谷比';
            app.Spinner = uispinner(app.LeftPanel);
            app.Spinner.Step = 0.1;
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @OnRatioChanged, true);
            app.Spinner.Value = 2.4;
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '最小峰峰距';
            app.Spinner_2 = uispinner(app.LeftPanel);
            app.Spinner_2.Step = 0.01;
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @OnIntervalChanged, true);
            app.Spinner_2.Value = 0.12;
            app.HzListBoxLabel = uilabel(app.LeftPanel);
            app.HzListBoxLabel.Text = '频率(Hz)';
            app.HzListBox = uilistbox(app.LeftPanel);
            app.HzListBox.Items = {};
            app.HzListBox.ClickedFcn = createCallbackFcn(app, @OnListClicked, true);
            app.HzListBox.Value = {};
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '基频搜索区间';
            app.Base_freq_search_interval = uispinner(app.LeftPanel);
            app.Base_freq_search_interval.Step = 0.01;
            app.Base_freq_search_interval.Value = 0.025;
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '谐波幅度阈值';
            app.Harmonic_search_amp_ths = uispinner(app.LeftPanel);
            app.Harmonic_search_amp_ths.Step = 0.01;
            app.Harmonic_search_amp_ths.Value = 0.05;
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '谐波搜索区间';
            app.Harmonic_search_interval = uispinner(app.LeftPanel);
            app.Harmonic_search_interval.Step = 0.01;
            app.Harmonic_search_interval.Value = 0.1;
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '基频幅度阈值';
            app.Base_freq_amp_ths = uispinner(app.LeftPanel);
            app.Base_freq_amp_ths.Step = 0.1;
            app.Base_freq_amp_ths.Value = 0.3;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIFigWave = uiaxes(app.RightPanel);
            title(app.UIFigWave, '样本波形')
            xlabel(app.UIFigWave, 't/s')
            ylabel(app.UIFigWave, '振幅')
            zlabel(app.UIFigWave, 'Z')
            app.UIFigWave.XLimitMethod = 'tight';
            app.UIFigWave.YLimitMethod = 'tight';
            app.UIFigSpec = uiaxes(app.RightPanel);
            title(app.UIFigSpec, '频谱图')
            xlabel(app.UIFigSpec, 'f/Hz')
            ylabel(app.UIFigSpec, '幅度')
            zlabel(app.UIFigSpec, 'Z')
            app.UIFigSpec.XLimitMethod = 'tight';
            app.UIFigSpec.YLimitMethod = 'tight';
            app.UIFigBeat = uiaxes(app.RightPanel);
            title(app.UIFigBeat, '节拍划分')
            xlabel(app.UIFigBeat, 't/s')
            ylabel(app.UIFigBeat, '振幅')
            zlabel(app.UIFigBeat, 'Z')
            app.UIFigBeat.XLimitMethod = 'tight';
            app.UIFigBeat.YLimitMethod = 'tight';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exer_3_12

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end